classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        JointValuesTextArea          matlab.ui.control.TextArea
        JointValuesTextAreaLabel     matlab.ui.control.Label
        CoordinatesTextArea          matlab.ui.control.TextArea
        CoordinatesTextAreaLabel     matlab.ui.control.Label
        ShowPositionButton           matlab.ui.control.Button
        ZCoordinateEditField_2       matlab.ui.control.NumericEditField
        ZCoordinateEditField_2Label  matlab.ui.control.Label
        YCoordinateEditField_2       matlab.ui.control.NumericEditField
        YCoordinateEditField_2Label  matlab.ui.control.Label
        XCoordinateEditField_4       matlab.ui.control.NumericEditField
        XCoordinateEditField_4Label  matlab.ui.control.Label
        MoveXYZButton_2              matlab.ui.control.Button
        IncrementSizeEditField       matlab.ui.control.NumericEditField
        IncrementSizeEditFieldLabel  matlab.ui.control.Label
        ZButton                      matlab.ui.control.Button
        ZButton_2                    matlab.ui.control.Button
        XButton_2                    matlab.ui.control.Button
        XButton                      matlab.ui.control.Button
        YButton_2                    matlab.ui.control.Button
        YButton                      matlab.ui.control.Button
        EStopButton                  matlab.ui.control.Button
        RobotModelButtonGroup        matlab.ui.container.ButtonGroup
        ABBButton                    matlab.ui.control.ToggleButton
        LinearUR3Button              matlab.ui.control.ToggleButton
        MoveJointButton              matlab.ui.control.Button
        Q7EditField                  matlab.ui.control.NumericEditField
        Q7EditFieldLabel             matlab.ui.control.Label
        Q6EditField                  matlab.ui.control.NumericEditField
        Q6EditFieldLabel             matlab.ui.control.Label
        Q5EditField                  matlab.ui.control.NumericEditField
        Q5EditFieldLabel             matlab.ui.control.Label
        Q3EditField                  matlab.ui.control.NumericEditField
        Q3EditFieldLabel             matlab.ui.control.Label
        Q4EditField                  matlab.ui.control.NumericEditField
        Q4EditFieldLabel             matlab.ui.control.Label
        Q2EditField                  matlab.ui.control.NumericEditField
        Q2EditFieldLabel             matlab.ui.control.Label
        Q1EditField                  matlab.ui.control.NumericEditField
        Q1EditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        eStopController  % Instance of EStopController
        UR3MovementInst  % Instance of UR3Movement
        ABBMovementInst  % Instance of ABBMovement
    end
    
    methods (Access = public)
        function initializeAppWithInstances(app, UR3MovementInstance, ABBMovementInstance, EStopControllerInstance)
             % Assign the instances to the app properties
            app.UR3MovementInst = UR3MovementInstance;
            app.ABBMovementInst = ABBMovementInstance;
            app.eStopController = EStopControllerInstance;
        end
    end
    
    
    methods (Access = private)
        
        function updateJointValues(app)
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                return;
            end
    
            % Get the current joint positions
            qCurrent = robotInstance.model.getpos();
            
            % Update the EditFields with the current joint values
            app.JointValueEditField_1.Value = qCurrent(1);
            app.JointValueEditField_2.Value = qCurrent(2);
            app.JointValueEditField_3.Value = qCurrent(3);
            app.JointValueEditField_4.Value = qCurrent(4);
            app.JointValueEditField_5.Value = qCurrent(5);
            app.JointValueEditField_6.Value = qCurrent(6);
            app.JointValueEditField_7.Value = qCurrent(7);
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EStopButton
        function EStopButtonToggled(app, event)
            if ~app.eStopController.eStopEngaged
                % Engage the emergency stop on both robots
                app.eStopController.EngageEStop();
                app.EStopButton.Text = 'Resume';
            else
                % Disengage the emergency stop on both robots
                app.eStopController.DisengageEStop();
                app.EStopButton.Text = 'EStop';
            end
        end

        % Button pushed function: MoveJointButton
        function MoveJointButtonPushed(app, event)
                % Collect the user inputs for joint angles
                jointAngles = [
                    app.Q1EditField.Value;
                    app.Q2EditField.Value;
                    app.Q3EditField.Value;
                    app.Q4EditField.Value;
                    app.Q5EditField.Value;
                    app.Q6EditField.Value;
                    app.Q7EditField.Value;
                ];
        
                % Check which robot is selected
                if app.LinearUR3Button.Value
                    % Move the UR3 robot using the collected joint angles
                    app.UR3MovementInst.MoveToQJointConfiguration(jointAngles');
                elseif app.ABBButton.Value
                    % Move the ABB robot using the collected joint angles
                    app.ABBMovementInst.MoveToQJointConfiguration(jointAngles');
                else
                    % If no valid robot is selected, display an error message
                    uialert(app.UIFigure, 'Please select a valid robot model.', 'Robot Selection Error');
                end
           
        end

        % Button pushed function: ZButton
        function ZPosButtonPushed(app, event)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeEditField.Value;
    
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
    
            % Get current joint positions and end-effector position
            qCurrent = robotInstance.model.getpos();
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
    
            % Update the z-coordinate
            xEnd = currentPos(1:3)';
            xEnd(3) = xEnd(3) + incrementSize;
    
            % Call the external moveEndEffector function
            moveEndEffector(robotInstance, xEnd);
        end

        % Button pushed function: ZButton_2
        function ZNegButtonPushed(app, event)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeEditField.Value;
    
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
    
            % Get current joint positions and end-effector position
            qCurrent = robotInstance.model.getpos();
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
    
            % Update the z-coordinate
            xEnd = currentPos(1:3)';
            xEnd(3) = xEnd(3) - incrementSize;
    
            % Call the external moveEndEffector function
            moveEndEffector(robotInstance, xEnd);
        end

        % Button pushed function: YButton
        function YNegButtonPushed(app, event)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeEditField.Value;
    
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
    
            % Get current joint positions and end-effector position
            qCurrent = robotInstance.model.getpos();
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
    
            % Update the y-coordinate
            xEnd = currentPos(1:3)';
            xEnd(2) = xEnd(2) - incrementSize;
    
            % Call the external moveEndEffector function
            moveEndEffector(robotInstance, xEnd);
        end

        % Button pushed function: YButton_2
        function YPosButtonPushed(app, event)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeEditField.Value;
    
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
    
            % Get current joint positions and end-effector position
            qCurrent = robotInstance.model.getpos();
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
    
            % Update the y-coordinate
            xEnd = currentPos(1:3)';
            xEnd(2) = xEnd(2) + incrementSize;
    
            % Call the external moveEndEffector function
            moveEndEffector(robotInstance, xEnd);     
        end

        % Button pushed function: XButton_2
        function XPosButtonPushed(app, event)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeEditField.Value;
    
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
    
            % Get current joint positions and end-effector position
            qCurrent = robotInstance.model.getpos();
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
    
            % Update the x-coordinate
            xEnd = currentPos(1:3)';
            xEnd(1) = xEnd(1) + incrementSize;
    
            % Move end-effector to new position
            moveEndEffector(robotInstance, xEnd);
        end

        % Button pushed function: XButton
        function XNegButtonPushed(app, event)
            % Fetch the increment size from the input field
            incrementSize = app.IncrementSizeEditField.Value;
    
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
    
            % Get current joint positions and end-effector position
            qCurrent = robotInstance.model.getpos();
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
    
            % Update the x-coordinate
            xEnd = currentPos(1:3)';
            xEnd(1) = xEnd(1) - incrementSize;
    
            % Move end-effector to new position
            moveEndEffector(robotInstance, xEnd);
        end

        % Button pushed function: MoveXYZButton_2
        function MoveXYZButton_2Pushed(app, event)
    
            % Get the coordinates from the user inputs
            xTarget = app.XCoordinateEditField_4.Value;
            yTarget = app.YCoordinateEditField_2.Value;
            zTarget = app.ZCoordinateEditField_2.Value;
        
            % Set the target position in Cartesian space
            xEnd = [xTarget, yTarget, zTarget];
        
            % Check which robot is selected and pass the correct robot instance to the external function
            if app.LinearUR3Button.Value
                % Move the UR3 robot using the target position
                moveEndEffector(app.UR3MovementInst.UR3, xEnd);
            elseif app.ABBButton.Value
                % Implement similar logic for the ABB robot
                moveEndEffector(app.ABBMovementInst.robot, xEnd);
            else
                % If no valid robot is selected, display an error message
                uialert(app.UIFigure, 'Please select a valid robot model.', 'Robot Selection Error');
            end
        end

        % Button pushed function: ShowPositionButton
        function ShowPositionButtonPushed(app, event)
            % Determine which robot is currently selected
            if app.LinearUR3Button.Value
                robotInstance = app.UR3MovementInst.UR3;
            elseif app.ABBButton.Value
                robotInstance = app.ABBMovementInst.robot;
            else
                uialert(app.UIFigure, 'Please select a robot model.', 'Robot Selection Error');
                return;
            end
        
            % Get the current joint positions (Q values)
            qCurrent = robotInstance.model.getpos();
        
            % Get the current end-effector position (X, Y, Z)
            currentPose = robotInstance.model.fkine(qCurrent);
            currentPos = transl(currentPose);
        
            % Format the X, Y, Z coordinates horizontally
            xyzText = sprintf('X: %.2f, Y: %.2f, Z: %.2f', currentPos(1), currentPos(2), currentPos(3));
        
            % Format the Q values vertically
            qText = sprintf('Q1: %.2f\nQ2: %.2f\nQ3: %.2f\nQ4: %.2f\nQ5: %.2f\nQ6: %.2f\nQ7: %.2f', ...
                            qCurrent(1), qCurrent(2), qCurrent(3), qCurrent(4), qCurrent(5), qCurrent(6), qCurrent(7));

        
            % Display the output in the separate text areas
            app.CoordinatesTextArea.Value = xyzText;
            app.JointValuesTextArea.Value = qText;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Q1EditFieldLabel = uilabel(app.Panel);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.Panel, 'numeric');
            app.Q2EditFieldLabel = uilabel(app.Panel);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.Panel, 'numeric');
            app.Q4EditFieldLabel = uilabel(app.Panel);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.Panel, 'numeric');
            app.Q3EditFieldLabel = uilabel(app.Panel);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.Panel, 'numeric');
            app.Q5EditFieldLabel = uilabel(app.Panel);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.Panel, 'numeric');
            app.Q6EditFieldLabel = uilabel(app.Panel);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.Panel, 'numeric');
            app.Q7EditFieldLabel = uilabel(app.Panel);
            app.Q7EditFieldLabel.Text = 'Q7';
            app.Q7EditField = uieditfield(app.Panel, 'numeric');
            app.MoveJointButton = uibutton(app.Panel, 'push');
            app.MoveJointButton.ButtonPushedFcn = createCallbackFcn(app, @MoveJointButtonPushed, true);
            app.MoveJointButton.Text = 'MoveJoint';
            app.RobotModelButtonGroup = uibuttongroup(app.Panel);
            app.RobotModelButtonGroup.Title = 'Robot Model';
            app.LinearUR3Button = uitogglebutton(app.RobotModelButtonGroup);
            app.LinearUR3Button.Text = 'LinearUR3';
            app.LinearUR3Button.Value = true;
            app.ABBButton = uitogglebutton(app.RobotModelButtonGroup);
            app.ABBButton.Text = 'ABB';
            app.EStopButton = uibutton(app.Panel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonToggled, true);
            app.EStopButton.Text = 'EStop';
            app.YButton = uibutton(app.Panel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YNegButtonPushed, true);
            app.YButton.Text = '-Y';
            app.YButton_2 = uibutton(app.Panel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YPosButtonPushed, true);
            app.YButton_2.Text = '+Y';
            app.XButton = uibutton(app.Panel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XNegButtonPushed, true);
            app.XButton.Text = '-X';
            app.XButton_2 = uibutton(app.Panel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XPosButtonPushed, true);
            app.XButton_2.Text = '+X';
            app.ZButton_2 = uibutton(app.Panel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZNegButtonPushed, true);
            app.ZButton_2.Text = '-Z';
            app.ZButton = uibutton(app.Panel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZPosButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.IncrementSizeEditFieldLabel = uilabel(app.Panel);
            app.IncrementSizeEditFieldLabel.Text = 'IncrementSize';
            app.IncrementSizeEditField = uieditfield(app.Panel, 'numeric');
            app.MoveXYZButton_2 = uibutton(app.Panel, 'push');
            app.MoveXYZButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveXYZButton_2Pushed, true);
            app.MoveXYZButton_2.Text = 'MoveXYZ';
            app.XCoordinateEditField_4Label = uilabel(app.Panel);
            app.XCoordinateEditField_4Label.Text = 'X Coordinate';
            app.XCoordinateEditField_4 = uieditfield(app.Panel, 'numeric');
            app.YCoordinateEditField_2Label = uilabel(app.Panel);
            app.YCoordinateEditField_2Label.Text = 'Y Coordinate';
            app.YCoordinateEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ZCoordinateEditField_2Label = uilabel(app.Panel);
            app.ZCoordinateEditField_2Label.Text = 'Z Coordinate';
            app.ZCoordinateEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ShowPositionButton = uibutton(app.Panel, 'push');
            app.ShowPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPositionButtonPushed, true);
            app.ShowPositionButton.Text = 'ShowPosition';
            app.CoordinatesTextAreaLabel = uilabel(app.Panel);
            app.CoordinatesTextAreaLabel.Text = 'Coordinates';
            app.CoordinatesTextArea = uitextarea(app.Panel);
            app.CoordinatesTextArea.Editable = 'off';
            app.JointValuesTextAreaLabel = uilabel(app.Panel);
            app.JointValuesTextAreaLabel.Text = 'Joint Values';
            app.JointValuesTextArea = uitextarea(app.Panel);
            app.JointValuesTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end